classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SeleccionarimagenButton      matlab.ui.control.Button
        SistemainteligenteLabel      matlab.ui.control.Label
        Paso1GrabaraudioLabel        matlab.ui.control.Label
        Paso2SeleccionarimagenLabel  matlab.ui.control.Label
        GrabarvocalButton            matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        VocalLabel                   matlab.ui.control.Label
        RalEduardoCanultuzProyectofinalLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        net
        signalNet
        vowel = "A";
    end

    methods (Access = public)
    
        function characteristics = getCharacteristics(app,imgName)
            % LECTURA DE LA IMAGEN
            I = imread(imgName);
    
            % SEGMENTACION DE LA IMAGEN
            J =not(I(:,:,1)>192);
            J = bwareaopen(J,5,4);
            [L,n] = bwlabel(J,4);
    
            stats = regionprops(L,'all');
            characteristics = zeros(n, 2);
    
            % OBTENER LOS 3 DESCRIPTORES DE LA IMAGEN
            for i = 1:n
                characteristics(i, 1) = stats(i).MinorAxisLength / stats(i).MajorAxisLength;
                characteristics(i, 2) = stats(i).Eccentricity;
            end
        end
        
        function net = trainNet(app)
            % OBTENER LAS CARACTERISTICAS DE LAS IMAGENES DE ENTRENAMIENTO

            % BOLD
            charAB = getCharacteristics('TAB.png');
            charEB = getCharacteristics('TEB.png');
            charIB = getCharacteristics('TIB.png');
            charOB = getCharacteristics('TOB.png');
            charUB = getCharacteristics('TUB.png');
            charAR = getCharacteristics('TAR.png');
            charER = getCharacteristics('TER.png');
            charIR = getCharacteristics('TIR.png');
            charOR = getCharacteristics('TOR.png');
            charUR = getCharacteristics('TUR.png');
            charAI = getCharacteristics('TAI.png');
            charEI = getCharacteristics('TEI.png');
            charII = getCharacteristics('TII.png');
            charOI = getCharacteristics('TOI.png');
            charUI = getCharacteristics('TUI.png');

            characteristics = cat(1,charAB, charEB, charIB, charOB, charUB,charAR, charER, charIR, charOR, charUR, charAI, charEI, charII, charOI, charUI );

            net = selforgmap([5,2]);
            net.trainParam.epochs = 1000;
            net = train(net,characteristics');
        end
        
        function result = isVowelSelected(app, group)
            result = false;
            
            switch app.vowel
                case "A"
                    result = group == 2 || group == 11;
                case "E"
                    result = group == 3 || group == 4 || group == 8;
                case "I"
                    result = group == 5 || group == 9 || group == 10;
                case "O"
                    result = group == 1 || group == 6 || group == 7;
                case "U"
                    result = group == 1 || group == 2 || group == 6 || group == 7;
            end
        end
        
        function net = trainVowelNet(app)
            % DEFINICION DE VARIABLES
            Fs = 44100;
            order = 12;
            
            foldersPath = ["A/A", "E/E", "I/I", "O/O", "U/U"];
            audioFormat = ".wav";
            vowels = 5;
            samplesPerVowel = 25;
            totalSamples = vowels * samplesPerVowel;
            characteristics = zeros(totalSamples, 2);
            n=1;
            
            % LEER ELEMENTOS DE ENTRENAMIENTO
            for i=1:vowels
                folder = foldersPath(i);
    
                for j=1:samplesPerVowel
                    audioName = strcat(folder, string(j));
                    audioPath = strcat(audioName, audioFormat);
                    [y,Fs] = audioread(audioPath);
        
                    % OBTENER LOS DESCRIPTORES DE LOS AUDIOS
                    [pxx,f] = pyulear(y,order);
                    freq = meanfreq(pxx, f);
                    meanpeaks = mean(findpeaks(pxx));
        
                    characteristics(n,1) = freq;
                    characteristics(n,2) = meanpeaks;
        
                    n = n + 1;
                end
            end
            
            %DEFINICIÓN SOM
            net = selforgmap([5 5]);
            net.trainParam.epochs = 1000;
            net = train(net,characteristics');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.signalNet = app.trainVowelNet();
            app.net = app.trainNet();
        end

        % Button pushed function: SeleccionarimagenButton
        function SeleccionarimagenButtonPushed(app, event)
            % SELECCIONAR ARCHIVO
            file = uigetfile({'*.png'}, 'File Selector');
            I = imread(file);
            IG = rgb2gray(I);
            [N2,M2] = size(IG);
            
            % LIMPIAR SECCIONES PEQUEÑAS
            J2 =not(IG >192);
            J2 = bwareaopen(J2,5,8); 
            
            % SEGMENTAR LA IMAGEN
            [L2,n] = bwlabel(J2, 4);
            
            % CARACTERISTICAS GENERALES
            stats = regionprops(L2,'all');
            descriptors = zeros(n, 2);
            
            for i = 1:n
                rAxes = stats(i).MinorAxisLength / stats(i).MajorAxisLength;
                eccen = stats(i).Eccentricity;
                
                descriptors(i, 1) = rAxes;
                descriptors(i, 2) = eccen;
            end
            
            % METER DATOS A LA RED NEURONAL
            res = app.net(descriptors');
            elementsClassified = vec2ind(res);
            
            Vo = zeros(N2,M2);
            Im = zeros(N2,M2);

            % FILTRAR RESULTADOS
            for i = 1:n
                if( app.isVowelSelected( elementsClassified(1, i)) )
                    Vo(stats(i).PixelIdxList) = ones(stats(i).Area,1);
                else
                    Im(stats(i).PixelIdxList) = ones(stats(i).Area,1);
                end
            end

            figure(3)
            imshow([Vo;ones(30,M2);Im],[])
        end

        % Button pushed function: GrabarvocalButton
        function GrabarvocalButtonPushed(app, event)
            % EJECUCIÓN
            Fs = 11025;
            order = 12;
            nBits = 24;
            nCanales = 1;
            tiempo = 4; %tiempo de grabación
            characteristics = zeros(1, 2);
            
            recObj = audiorecorder(Fs, nBits, nCanales);
                app.Label.Text = "Grabando audio...";
                    recordblocking(recObj, tiempo);
                app.Label.Text = "Audio grabado.";
            
            y = getaudiodata(recObj);

            [pxx,f] = pyulear(y,order);
            freq = meanfreq(pxx, f);
            meanpeaks = mean(findpeaks(pxx));

            characteristics(1, 1) = freq;
            characteristics(1, 2) = meanpeaks;
            
            result = app.signalNet(characteristics');
            data = vec2ind(result);
            
            switch(data(1,1))
                case {3, 6, 7, 11, 16, 21, 22}
                    app.vowel = "A";
                    app.Label.Text = app.vowel;
                case {2, 8, 12}
                    app.vowel = "E";
                    app.Label.Text = app.vowel;
                case {1, 5, 15}
                    app.vowel = "I";
                    app.Label.Text = app.vowel;
                case {13, 14, 19, 23, 25}
                    app.vowel = "O";
                    app.Label.Text = app.vowel;
                case {4, 9, 10}
                    app.vowel = "U";
                    app.Label.Text = app.vowel;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SeleccionarimagenButton = uibutton(app.UIFigure, 'push');
            app.SeleccionarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarimagenButtonPushed, true);
            app.SeleccionarimagenButton.Text = 'Seleccionar imagen';
            app.SistemainteligenteLabel = uilabel(app.UIFigure);
            app.SistemainteligenteLabel.Text = 'Sistema inteligente';
            app.Paso1GrabaraudioLabel = uilabel(app.UIFigure);
            app.Paso1GrabaraudioLabel.Text = 'Paso 1- Grabar audio';
            app.Paso2SeleccionarimagenLabel = uilabel(app.UIFigure);
            app.Paso2SeleccionarimagenLabel.Text = 'Paso 2 - Seleccionar imagen';
            app.GrabarvocalButton = uibutton(app.UIFigure, 'push');
            app.GrabarvocalButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarvocalButtonPushed, true);
            app.GrabarvocalButton.Text = 'Grabar vocal';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.VocalLabel = uilabel(app.UIFigure);
            app.VocalLabel.Text = 'Vocal:';
            app.RalEduardoCanultuzProyectofinalLabel = uilabel(app.UIFigure);
            app.RalEduardoCanultuzProyectofinalLabel.Text = 'Raúl Eduardo Canul tuz - Proyecto final';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end